classdef Depresja_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                       matlab.ui.Figure
        uipanel1                      matlab.ui.container.Panel
        text2                         matlab.ui.control.Label
        SlicerRelacje                 matlab.ui.control.Slider
        text3                         matlab.ui.control.Label
        text4                         matlab.ui.control.Label
        SliderOdczucieSmutku          matlab.ui.control.Slider
        text5                         matlab.ui.control.Label
        text6                         matlab.ui.control.Label
        SliderZdecydowanie            matlab.ui.control.Slider
        text7                         matlab.ui.control.Label
        text8                         matlab.ui.control.Label
        SliderOdczuwaniePrzyjemnosci  matlab.ui.control.Slider
        text10                        matlab.ui.control.Label
        text11                        matlab.ui.control.Label
        SliderMysliSamobojcze         matlab.ui.control.Slider
        SliderPoczucieWiny            matlab.ui.control.Slider
        text12                        matlab.ui.control.Label
        text13                        matlab.ui.control.Label
        text14                        matlab.ui.control.Label
        Btn_Stan_Emocjonalny          matlab.ui.control.Button
        text27                        matlab.ui.control.Label
        TxtStanEmocjonalny2           matlab.ui.control.Label
        uipanel2                      matlab.ui.container.Panel
        text15                        matlab.ui.control.Label
        SliderStabilnoscWagi          matlab.ui.control.Slider
        text17                        matlab.ui.control.Label
        text18                        matlab.ui.control.Label
        SliderProblemyZeSnem          matlab.ui.control.Slider
        text19                        matlab.ui.control.Label
        text20                        matlab.ui.control.Label
        SliderApetyt                  matlab.ui.control.Slider
        text21                        matlab.ui.control.Label
        text22                        matlab.ui.control.Label
        SliderEnergia                 matlab.ui.control.Slider
        text23                        matlab.ui.control.Label
        Btn_Ryzyko_Depresji           matlab.ui.control.Button
        text30                        matlab.ui.control.Label
        TxtRyzykoDepresji             matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Depresja_OpeningFcn(app)
            movegui(app.figure1,"center");
%             % This function has no output args, see OutputFcn.
%             % hObject    handle to figure
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    structure with handles and user data (see GUIDATA)
%             % varargin   command line arguments to Depresja (see VARARGIN)
%             
%             % Choose default command line output for Depresja
%             app.output = hObject;
%             
%             % Update handles structure
%             guidata(hObject, handles);
        end

        % Button pushed function: Btn_Ryzyko_Depresji
        function Btn_Ryzyko_Depresji_Callback(app, event)
            % hObject    handle to Btn_Ryzyko_Depresji (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            stabilnosc_wagi = get(app.SliderStabilnoscWagi, 'Value');
            problemy_ze_snem = get(app.SliderProblemyZeSnem, 'Value');
            apetyt = get(app.SliderApetyt, 'Value');
            energia_w_ciagu_dnia = get(app.SliderEnergia, 'Value');
            stan_emocjonalny = str2double(get(app.TxtStanEmocjonalny2, 'Text'));
            
            FIS_ryzyko_depresji = readfis('FISDepresja.fis');
            inputs = [stabilnosc_wagi, problemy_ze_snem, apetyt, energia_w_ciagu_dnia, stan_emocjonalny];
            [output_ryzyko_depresji]=evalfis(FIS_ryzyko_depresji, inputs);
            app.TxtRyzykoDepresji.Text = num2str(output_ryzyko_depresji);
        end

        % Button pushed function: Btn_Stan_Emocjonalny
        function Btn_Stan_Emocjonalny_Callback(app, event)
            % hObject    handle to Btn_Stan_Emocjonalny (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            relacje = get(app.SlicerRelacje, 'Value');
            odczucie_smutku = get(app.SliderOdczucieSmutku, 'Value');
            zdecydowanie = get(app.SliderZdecydowanie, 'Value');
            odczuwanie_przyjemnosci = get(app.SliderOdczuwaniePrzyjemnosci, 'Value');
            mysli_samobojcze = get(app.SliderMysliSamobojcze, 'Value');
            poczucie_winy = get(app.SliderPoczucieWiny, 'Value');
            
            FIS_stan_emocjonalny = readfis('Emocje.fis');
            inputs = [relacje, odczucie_smutku, zdecydowanie, odczuwanie_przyjemnosci, mysli_samobojcze, poczucie_winy];
            [output_stan_emocjonalny]=evalfis(FIS_stan_emocjonalny, inputs);
            
            app.TxtStanEmocjonalny2.Text = num2str(output_stan_emocjonalny);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Depresja';
            app.uipanel1 = uipanel(app.figure1);
            app.text2 = uilabel(app.uipanel1);
            app.text2.Text = 'Relacje z innymi';
            app.SlicerRelacje = uislider(app.uipanel1);
            app.SlicerRelacje.Limits = [0 1];
            app.SlicerRelacje.MajorTicks = [];
            app.SlicerRelacje.MinorTicks = [];
            app.text3 = uilabel(app.uipanel1);
            app.text3.Text = 'szczątkowe skomplikowane normalne silne';
            app.text4 = uilabel(app.uipanel1);
            app.text4.Text = 'Odczuwanie smutku';
            app.SliderOdczucieSmutku = uislider(app.uipanel1);
            app.SliderOdczucieSmutku.Limits = [0 1];
            app.SliderOdczucieSmutku.MajorTicks = [];
            app.SliderOdczucieSmutku.MinorTicks = [];
            app.text5 = uilabel(app.uipanel1);
            app.text5.Text = 'brak      sporadyczne     częste      stałe';
            app.text6 = uilabel(app.uipanel1);
            app.text6.Text = 'Zdecydowanie';
            app.SliderZdecydowanie = uislider(app.uipanel1);
            app.SliderZdecydowanie.Limits = [0 1];
            app.SliderZdecydowanie.MajorTicks = [];
            app.SliderZdecydowanie.MinorTicks = [];
            app.text7 = uilabel(app.uipanel1);
            app.text7.Text = 'brak         małe        średnie       duże';
            app.text8 = uilabel(app.uipanel1);
            app.text8.Text = 'Odczuwanie przyjemności';
            app.SliderOdczuwaniePrzyjemnosci = uislider(app.uipanel1);
            app.SliderOdczuwaniePrzyjemnosci.Limits = [0 1];
            app.SliderOdczuwaniePrzyjemnosci.MajorTicks = [];
            app.SliderOdczuwaniePrzyjemnosci.MinorTicks = [];
            app.text10 = uilabel(app.uipanel1);
            app.text10.Text = 'Myśli samobójcze';
            app.text11 = uilabel(app.uipanel1);
            app.text11.Text = 'Poczucie winy';
            app.SliderMysliSamobojcze = uislider(app.uipanel1);
            app.SliderMysliSamobojcze.Limits = [0 1];
            app.SliderMysliSamobojcze.MajorTicks = [];
            app.SliderMysliSamobojcze.MinorTicks = [];
            app.SliderPoczucieWiny = uislider(app.uipanel1);
            app.SliderPoczucieWiny.Limits = [0 1];
            app.SliderPoczucieWiny.MajorTicks = [];
            app.SliderPoczucieWiny.MinorTicks = [];
            app.text12 = uilabel(app.uipanel1);
            app.text12.Text = 'brak           spekulacje         zdecydowane';
            app.text13 = uilabel(app.uipanel1);
            app.text13.Text = 'brak                     małe                     duże';
            app.text14 = uilabel(app.uipanel1);
            app.text14.Text = 'brak         małe        średnie       duże';
            app.Btn_Stan_Emocjonalny = uibutton(app.uipanel1, 'push');
            app.Btn_Stan_Emocjonalny.ButtonPushedFcn = createCallbackFcn(app, @Btn_Stan_Emocjonalny_Callback, true);
            app.Btn_Stan_Emocjonalny.Text = 'Oblicz';
            app.text27 = uilabel(app.uipanel1);
            app.text27.Text = 'Stan emocjonalny';
            app.TxtStanEmocjonalny2 = uilabel(app.uipanel1);
            app.TxtStanEmocjonalny2.Text = '';
            app.uipanel2 = uipanel(app.figure1);
            app.text15 = uilabel(app.uipanel2);
            app.text15.Text = 'Stabilność wagi';
            app.SliderStabilnoscWagi = uislider(app.uipanel2);
            app.SliderStabilnoscWagi.Limits = [0 1];
            app.SliderStabilnoscWagi.MajorTicks = [];
            app.SliderStabilnoscWagi.MinorTicks = [];
            app.text17 = uilabel(app.uipanel2);
            app.text17.Text = 'utrata                      stabilność                przybranie';
            app.text18 = uilabel(app.uipanel2);
            app.text18.Text = 'Problemy ze snem';
            app.SliderProblemyZeSnem = uislider(app.uipanel2);
            app.SliderProblemyZeSnem.Limits = [0 1];
            app.SliderProblemyZeSnem.MajorTicks = [];
            app.SliderProblemyZeSnem.MinorTicks = [];
            app.text19 = uilabel(app.uipanel2);
            app.text19.Text = 'brak problemów nieregularność ciągła senność bezsenność';
            app.text20 = uilabel(app.uipanel2);
            app.text20.Text = 'Apetyt';
            app.SliderApetyt = uislider(app.uipanel2);
            app.SliderApetyt.Limits = [0 1];
            app.SliderApetyt.MajorTicks = [];
            app.SliderApetyt.MinorTicks = [];
            app.text21 = uilabel(app.uipanel2);
            app.text21.Text = 'brak       mały      normalny      duży      nadmierny';
            app.text22 = uilabel(app.uipanel2);
            app.text22.Text = 'Energia w ciągu dnia';
            app.SliderEnergia = uislider(app.uipanel2);
            app.SliderEnergia.Limits = [0 1];
            app.SliderEnergia.MajorTicks = [];
            app.SliderEnergia.MinorTicks = [];
            app.text23 = uilabel(app.uipanel2);
            app.text23.Text = 'brak            mało            dużo           nadpobudliwość';
            app.Btn_Ryzyko_Depresji = uibutton(app.uipanel2, 'push');
            app.Btn_Ryzyko_Depresji.ButtonPushedFcn = createCallbackFcn(app, @Btn_Ryzyko_Depresji_Callback, true);
            app.Btn_Ryzyko_Depresji.Text = 'Oblicz';
            app.text30 = uilabel(app.uipanel2);
            app.text30.Text = 'Ryzyko depresji:';
            app.TxtRyzykoDepresji = uilabel(app.uipanel2);
            app.TxtRyzykoDepresji.Text = '';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Depresja_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @Depresja_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end